classdef TTL_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UpdatePreview                 matlab.ui.control.Button
        TrialDesignPanel              matlab.ui.container.Panel
        CatchEditField                matlab.ui.control.NumericEditField
        CatchEditFieldLabel           matlab.ui.control.Label
        WhiskerEditField              matlab.ui.control.NumericEditField
        WhiskerEditFieldLabel         matlab.ui.control.Label
        AuditoryEditField             matlab.ui.control.NumericEditField
        AuditoryEditFieldLabel        matlab.ui.control.Label
        ProbabilitiesLabel            matlab.ui.control.Label
        BlockDesignPanel              matlab.ui.container.Panel
        TTLCh1ScheduleLamp            matlab.ui.control.Lamp
        TTLCh2ScheduleLamp            matlab.ui.control.Lamp
        TTLCh2StartButton             matlab.ui.control.StateButton
        RepeatTTLCh1ButtonGroup       matlab.ui.container.ButtonGroup
        RepeatTrialsEditField         matlab.ui.control.NumericEditField
        NeverButton                   matlab.ui.control.RadioButton
        EveryBlockStartButton         matlab.ui.control.RadioButton
        EveryNTrialsButton            matlab.ui.control.RadioButton
        NextBlockCheckBox             matlab.ui.control.CheckBox
        SessionStartCheckBox          matlab.ui.control.CheckBox
        TTLCh1startLabel              matlab.ui.control.Label
        BlockDesignButtonGroup        matlab.ui.container.ButtonGroup
        AlternateBlocksButton         matlab.ui.control.RadioButton
        ContinuousButton              matlab.ui.control.RadioButton
        SecondHalfButton              matlab.ui.control.RadioButton
        FirstHalfButton               matlab.ui.control.RadioButton
        Image                         matlab.ui.control.Image
        ExpDesignButtonGroup          matlab.ui.container.ButtonGroup
        TrialButton                   matlab.ui.control.RadioButton
        BlockButton                   matlab.ui.control.RadioButton
        DigitaloutputlinesLabel       matlab.ui.control.Label
        ch1displayCheckBox            matlab.ui.control.CheckBox
        ch2displayCheckBox            matlab.ui.control.CheckBox
        Channel2Label                 matlab.ui.control.Label
        PulseWidthEditField_ch2       matlab.ui.control.NumericEditField
        PulsewidthmsEditFieldLabel_2  matlab.ui.control.Label
        DurationEditField_ch2         matlab.ui.control.NumericEditField
        DurationmsEditField_2Label_2  matlab.ui.control.Label
        PeriodEditField_ch2           matlab.ui.control.NumericEditField
        PeriodmsEditFieldLabel_2      matlab.ui.control.Label
        DelayEditField_ch2            matlab.ui.control.NumericEditField
        DelaymsEditFieldLabel_2       matlab.ui.control.Label
        AmplitudeEditField_ch2        matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel_2     matlab.ui.control.Label
        ArmTTLCheckBox_ch2            matlab.ui.control.CheckBox
        NumPulsesLabel_ch2            matlab.ui.control.Label
        FrequencyPulsesLabel_ch2      matlab.ui.control.Label
        PulseWidthEditField_ch1       matlab.ui.control.NumericEditField
        PulsewidthmsEditFieldLabel    matlab.ui.control.Label
        PeriodEditField_ch1           matlab.ui.control.NumericEditField
        PeriodmsEditFieldLabel        matlab.ui.control.Label
        AmplitudeEditField_ch1        matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        DurationEditField_ch1         matlab.ui.control.NumericEditField
        DurationmsEditField_2Label    matlab.ui.control.Label
        DelayEditField_ch1            matlab.ui.control.NumericEditField
        DelaymsEditFieldLabel         matlab.ui.control.Label
        FrequencyPulsesLabel_ch1      matlab.ui.control.Label
        NumPulsesLabel_ch1            matlab.ui.control.Label
        ArmTTLCheckBox_ch1            matlab.ui.control.CheckBox
        Channel1Label                 matlab.ui.control.Label
        TTLAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
    end

    methods (Access = private)

        function p = get_params_ch1(app)
            p.delay_s  = app.DelayEditField_ch1.Value/1000;
            p.dur_s    = app.DurationEditField_ch1.Value/1000;
            p.period_s = app.PeriodEditField_ch1.Value/1000;
            p.pw_s     = app.PulseWidthEditField_ch1.Value/1000;
            p.amp      = app.AmplitudeEditField_ch1.Value;
        end

        function p = get_params_ch2(app)
            p.delay_s  = app.DelayEditField_ch2.Value/1000;
            p.dur_s    = app.DurationEditField_ch2.Value/1000;
            p.period_s = app.PeriodEditField_ch2.Value/1000;
            p.pw_s     = app.PulseWidthEditField_ch2.Value/1000;
            p.amp      = app.AmplitudeEditField_ch2.Value;
        end

    end   
    methods (Access = public)   
    
    % TTL ch1 schedule lamp

        function update_ttl_schedule_lamp(app)

            if app.SessionStartCheckBox.Value
                app.TTLCh1ScheduleLamp.Color = 'g';      % active immediately

            elseif app.NextBlockCheckBox.Value
                app.TTLCh1ScheduleLamp.Color = 'b';      % waiting for next block

            else
                app.TTLCh1ScheduleLamp.Color = 'y';      % inactive / not scheduled
            end

        end

        %% TTL ch2 lamp

        function update_ttl_ch2_lamp(app)

            if strcmp(app.TTLCh2StartButton.Enable,'off')
                % Feature unavailable
                app.TTLCh2ScheduleLamp.Color = 'y';

            elseif app.TTLCh2StartButton.Value
                % Active
                app.TTLCh2ScheduleLamp.Color = 'g';

            else
                % Enabled but inactive
                app.TTLCh2ScheduleLamp.Color = 'r';
            end

        end

        %% Update design controls

        function update_design_controls(app)

            global TTL_info

            isBlock  = isfield(TTL_info,'exp_design')   && strcmp(TTL_info.exp_design,'Block');
            isTrial  = isfield(TTL_info,'exp_design')   && strcmp(TTL_info.exp_design,'Trial');
            isContinuous = isfield(TTL_info,'block_design') && strcmp(TTL_info.block_design,'Continuous');

            % Enable/disable whole panels
            app.BlockDesignPanel.Enable = matlab.lang.OnOffSwitchState(isBlock);
            app.TrialDesignPanel.Enable = matlab.lang.OnOffSwitchState(isTrial);

            % Block-only checkboxes
            if isBlock
                app.SessionStartCheckBox.Enable = 'on';
                app.NextBlockCheckBox.Enable = 'on';
            else
                app.SessionStartCheckBox.Enable = 'off';
                app.NextBlockCheckBox.Enable = 'off';

                app.SessionStartCheckBox.Value = false;
                app.NextBlockCheckBox.Value = false;
            end

            update_ttl_schedule_lamp(app);

            % Repeat group only for Block + Continuous
            if isBlock && isContinuous
                app.RepeatTTLCh1ButtonGroup.Enable = 'on';
                app.RepeatTrialsEditField.Enable = 'on';
                app.TTLCh2StartButton.Enable = 'on';
                if isempty(app.RepeatTTLCh1ButtonGroup.SelectedObject)
                    app.NeverButton.Value = true;
                end
            else
                app.RepeatTTLCh1ButtonGroup.Enable = 'off';
                app.RepeatTrialsEditField.Enable = 'off';
                app.NeverButton.Value = true;
                app.TTLCh2StartButton.Enable = 'off';
                app.TTLCh2StartButton.Value = false;
            end
            update_ttl_ch2_lamp(app);

        end
        %% Update TTL info

        function update_ttl_info(app)
            global TTL_info

            TTL_info = struct();
            TTL_info.valid = true;
            TTL_info.source = 'TTL_GUI';

            % Preview / playback fs
            TTL_info.fs = 10000;

            % Channel params
            TTL_info.ch1.p = get_params_ch1(app);
            TTL_info.ch2.p = get_params_ch2(app);

            % Experimental design
            selected = app.ExpDesignButtonGroup.SelectedObject;
            switch selected
                case app.BlockButton
                    TTL_info.exp_design = 'Block';
                case app.TrialButton
                    TTL_info.exp_design = 'Trial';
                otherwise
                    TTL_info.exp_design = '';
            end

            % Trial-design probabilities
            TTL_info.aud_prob = app.AuditoryEditField.Value;
            TTL_info.wh_prob = app.WhiskerEditField.Value;
            TTL_info.catch_prob = app.CatchEditField.Value;

            % Block-design settings
            selected = app.BlockDesignButtonGroup.SelectedObject;
            switch selected
                case app.AlternateBlocksButton
                    TTL_info.block_design = 'Alternate blocks';
                case app.FirstHalfButton
                    TTL_info.block_design = 'First half';
                case app.SecondHalfButton
                    TTL_info.block_design = 'Second half';
                case app.ContinuousButton
                    TTL_info.block_design = 'Continuous';
                otherwise
                    TTL_info.block_design = '';
            end

            if app.SessionStartCheckBox.Value
                TTL_info.ttl_ch1_start = 'Session start';
            elseif app.NextBlockCheckBox.Value
                TTL_info.ttl_ch1_start = 'Next block';
            else
                TTL_info.ttl_ch1_start = '';
            end

            selected = app.RepeatTTLCh1ButtonGroup.SelectedObject;
            switch selected
                case app.EveryNTrialsButton
                    TTL_info.repeat_ttl_ch1 = 'Every N trials';
                    TTL_info.repeat_ttl_ch1_n = max(1, round(app.RepeatTrialsEditField.Value));

                case app.EveryBlockStartButton
                    TTL_info.repeat_ttl_ch1 = 'Every block start';
                    TTL_info.repeat_ttl_ch1_n = [];

                case app.NeverButton
                    TTL_info.repeat_ttl_ch1 = 'Never';
                    TTL_info.repeat_ttl_ch1_n = [];

                otherwise
                    TTL_info.repeat_ttl_ch1 = '';
                    TTL_info.repeat_ttl_ch1_n = [];
            end

            TTL_info.ttl_ch2_start = logical(app.TTLCh2StartButton.Value);

            if ~strcmp(TTL_info.exp_design, 'Block')
                TTL_info.block_design = '';
                TTL_info.ttl_ch1_start = '';
                TTL_info.repeat_ttl_ch1 = '';
            end

            if ~strcmp(TTL_info.block_design, 'Continuous')
                TTL_info.repeat_ttl_ch1 = '';
            end

            TTL_info.last_update = now;

            update_design_controls(app);
        end

        %% Function to update the UI axes with both waveforms
        function update_ttl_preview(app)
            global baseline_window response_window_end artifact_window response_window

            % Refresh TTL_info from current GUI controls first
            update_ttl_info(app);

            fs = 10000;

            % Get raw GUI params
            p1 = get_params_ch1(app);
            p2 = get_params_ch2(app);

            % Anchors in seconds
            if isempty(baseline_window)
                ttl1_anchor_s = 0;
            else
                ttl1_anchor_s = baseline_window / 1000;
            end

            if ~isempty(response_window_end)
                ttl2_anchor_s = response_window_end;
            else
                % fallback if response_window_end has not been computed yet
                bw = 0;
                aw = 0;
                rw = 0;

                if ~isempty(baseline_window), bw = baseline_window; end
                if ~isempty(artifact_window), aw = artifact_window; end
                if ~isempty(response_window), rw = response_window; end

                ttl2_anchor_s = (bw + aw + rw) / 1000;
            end

            % Shift delays so preview is referenced to trial start
            p1_preview = p1;
            p2_preview = p2;

            p1_preview.delay_s = ttl1_anchor_s + p1.delay_s;
            p2_preview.delay_s = ttl2_anchor_s + p2.delay_s;

            % Compute previews
            [t1_ms, y1, nP1] = TTL_stim_compute_preview(p1_preview, fs);
            [t2_ms, y2, nP2] = TTL_stim_compute_preview(p2_preview, fs);

            ax = app.TTLAxes;
            cla(ax);
            hold(ax, 'on');

            plottedY = [];
            plottedTmax = [];

            % ---- CH1 ----
            if app.ch1displayCheckBox.Value
                plot(ax, t1_ms, y1, 'LineWidth', 1.5);
                plottedY = [plottedY; y1(:)];
                if ~isempty(t1_ms)
                    plottedTmax(end+1) = max(t1_ms);
                end

                app.NumPulsesLabel_ch1.Text = sprintf('N pulses: %d', nP1);
                if nP1 >= 2 && p1.period_s > 0
                    app.FrequencyPulsesLabel_ch1.Text = ...
                        sprintf('Frequency (Hz): %.2f', 1/p1.period_s);
                elseif nP1 == 1
                    app.FrequencyPulsesLabel_ch1.Text = 'Frequency (Hz): 0';
                else
                    app.FrequencyPulsesLabel_ch1.Text = 'Frequency (Hz): —';
                end
            else
                app.NumPulsesLabel_ch1.Text = 'N pulses: —';
                app.FrequencyPulsesLabel_ch1.Text = 'Frequency (Hz): —';
            end

            % ---- CH2 ----
            if app.ch2displayCheckBox.Value
                plot(ax, t2_ms, y2, 'LineWidth', 1.5, 'Color', [0 0.6 0]);
                plottedY = [plottedY; y2(:)];
                if ~isempty(t2_ms)
                    plottedTmax(end+1) = max(t2_ms);
                end

                app.NumPulsesLabel_ch2.Text = sprintf('N pulses: %d', nP2);
                if nP2 >= 2 && p2.period_s > 0
                    app.FrequencyPulsesLabel_ch2.Text = ...
                        sprintf('Frequency (Hz): %.2f', 1/p2.period_s);
                elseif nP2 == 1
                    app.FrequencyPulsesLabel_ch2.Text = 'Frequency (Hz): 0';
                else
                    app.FrequencyPulsesLabel_ch2.Text = 'Frequency (Hz): —';
                end
            else
                app.NumPulsesLabel_ch2.Text = 'N pulses: —';
                app.FrequencyPulsesLabel_ch2.Text = 'Frequency (Hz): —';
            end

            % ---- Stimulus start marker (baseline window end) ----
            stim_start_ms = 0;
            if ~isempty(baseline_window)
                stim_start_ms = baseline_window;
            end

            xline(ax, stim_start_ms, ':r', 'LineWidth', 1.5);

            hold(ax, 'off');
            grid(ax, 'on');

            ax.XLabel.String = 'Time from trial start (ms)';
            ax.YLabel.String = 'Amplitude';

            % ---- Title ----
            titleParts = {};
            if app.ch1displayCheckBox.Value
                titleParts{end+1} = sprintf('TTL1: %d pulses', nP1);
                if p1.period_s > 0 && p1.pw_s >= p1.period_s
                    titleParts{end+1} = 'CH1 width≥period';
                end
            end
            if app.ch2displayCheckBox.Value
                titleParts{end+1} = sprintf('TTL2: %d pulses', nP2);
                if p2.period_s > 0 && p2.pw_s >= p2.period_s
                    titleParts{end+1} = 'CH2 width≥period';
                end
            end

            if isempty(titleParts)
                ax.Title.String = 'TTL preview (no channels selected)';
            else
                ax.Title.String = strjoin(titleParts, '  |  ');
            end

            % ---- Axes limits ----
            if isempty(plottedY)
                ylim(ax, [0 0.1]);
                xlim(ax, [0 100]);
            else
                ymax = max(plottedY);
                pad = max(0.1 * ymax, 0.1);
                ylim(ax, [0 ymax + pad]);

                xmax = max([plottedTmax stim_start_ms]);
                if isempty(xmax) || xmax <= 0
                    xmax = 100;
                end
                xlim(ax, [0 xmax]);
            end

            drawnow;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global TTL_info

            % Reset stale global state from previous TTL_GUI instances
            TTL_info = struct();

            update_ttl_schedule_lamp(app);
            update_ttl_ch2_lamp(app);

            % Refresh info first, then draw preview from current controls
            update_ttl_info(app);
            update_ttl_preview(app)

            drawnow;

        end

        % Value changed function: DelayEditField_ch1
        function DelayEditField_ch1ValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: DurationEditField_ch1
        function DurationEditField_ch1ValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: AmplitudeEditField_ch1
        function AmplitudeEditField_ch1ValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: PeriodEditField_ch1
        function PeriodEditField_ch1ValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: PulseWidthEditField_ch1
        function PulseWidthEditField_ch1ValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: ArmTTLCheckBox_ch1
        function ArmTTLCheckBox_ch1ValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: DelayEditField_ch2
        function DelayEditField_ch2ValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: DurationEditField_ch2
        function DurationEditField_ch2ValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: AmplitudeEditField_ch2
        function AmplitudeEditField_ch2ValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: PeriodEditField_ch2
        function PeriodEditField_ch2ValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: PulseWidthEditField_ch2
        function PulseWidthEditField_ch2ValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: ArmTTLCheckBox_ch2
        function ArmTTLCheckBox_ch2ValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: ch1displayCheckBox
        function ch1displayCheckBoxValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: ch2displayCheckBox
        function ch2displayCheckBoxValueChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: AuditoryEditField
        function AuditoryEditFieldValueChanged(app, event)
            value = app.AuditoryEditField.Value;
            update_ttl_preview(app);
        end

        % Value changed function: WhiskerEditField
        function WhiskerEditFieldValueChanged(app, event)
            value = app.WhiskerEditField.Value;
            update_ttl_preview(app);
        end

        % Value changed function: CatchEditField
        function CatchEditFieldValueChanged(app, event)
            value = app.CatchEditField.Value;
            update_ttl_preview(app);
        end

        % Selection changed function: BlockDesignButtonGroup
        function BlockDesignButtonGroupSelectionChanged(app, event)
            update_ttl_preview(app);
        end

        % Selection changed function: ExpDesignButtonGroup
        function ExpDesignButtonGroupSelectionChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: SessionStartCheckBox
        function SessionStartCheckBoxValueChanged(app, event)
            if app.SessionStartCheckBox.Value
                app.NextBlockCheckBox.Value = false;
            end
            update_ttl_schedule_lamp(app);
            update_ttl_preview(app);
        end

        % Value changed function: NextBlockCheckBox
        function NextBlockCheckBoxValueChanged(app, event)
            if app.NextBlockCheckBox.Value
                app.SessionStartCheckBox.Value = false;
            end
            update_ttl_schedule_lamp(app);
            update_ttl_preview(app);
        end

        % Selection changed function: RepeatTTLCh1ButtonGroup
        function RepeatTTLCh1ButtonGroupSelectionChanged(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: TTLCh2StartButton
        function TTLCh2StartButtonValueChanged(app, event)
            update_ttl_ch2_lamp(app);
            update_ttl_preview(app);
        end

        % Button pushed function: UpdatePreview
        function UpdatePreviewButtonPushed(app, event)
            update_ttl_preview(app);
        end

        % Value changed function: RepeatTrialsEditField
        function RepeatTrialsEditFieldValueChanged(app, event)
            app.RepeatTrialsEditField.Value = max(1, round(app.RepeatTrialsEditField.Value));
            update_ttl_preview(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TTLAxes = uiaxes(app.UIFigure);
            title(app.TTLAxes, 'Title')
            xlabel(app.TTLAxes, 'X')
            ylabel(app.TTLAxes, 'Y')
            zlabel(app.TTLAxes, 'Z')
            app.Channel1Label = uilabel(app.UIFigure);
            app.Channel1Label.Text = 'Channel 1';
            app.ArmTTLCheckBox_ch1 = uicheckbox(app.UIFigure);
            app.ArmTTLCheckBox_ch1.ValueChangedFcn = createCallbackFcn(app, @ArmTTLCheckBox_ch1ValueChanged, true);
            app.ArmTTLCheckBox_ch1.Enable = 'off';
            app.ArmTTLCheckBox_ch1.Text = 'Active';
            app.NumPulsesLabel_ch1 = uilabel(app.UIFigure);
            app.NumPulsesLabel_ch1.Text = 'N pulses:';
            app.FrequencyPulsesLabel_ch1 = uilabel(app.UIFigure);
            app.FrequencyPulsesLabel_ch1.Text = 'Frequency (Hz):';
            app.DelaymsEditFieldLabel = uilabel(app.UIFigure);
            app.DelaymsEditFieldLabel.Text = 'Delay (ms)';
            app.DelayEditField_ch1 = uieditfield(app.UIFigure, 'numeric');
            app.DelayEditField_ch1.ValueChangedFcn = createCallbackFcn(app, @DelayEditField_ch1ValueChanged, true);
            app.DurationmsEditField_2Label = uilabel(app.UIFigure);
            app.DurationmsEditField_2Label.Text = 'Duration (ms)';
            app.DurationEditField_ch1 = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField_ch1.ValueChangedFcn = createCallbackFcn(app, @DurationEditField_ch1ValueChanged, true);
            app.DurationEditField_ch1.Value = 500;
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Enable = 'off';
            app.AmplitudeEditFieldLabel.Text = 'Amplitude ';
            app.AmplitudeEditField_ch1 = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField_ch1.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_ch1ValueChanged, true);
            app.AmplitudeEditField_ch1.Enable = 'off';
            app.AmplitudeEditField_ch1.Value = 1;
            app.PeriodmsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodmsEditFieldLabel.Text = 'Period (ms)';
            app.PeriodEditField_ch1 = uieditfield(app.UIFigure, 'numeric');
            app.PeriodEditField_ch1.ValueChangedFcn = createCallbackFcn(app, @PeriodEditField_ch1ValueChanged, true);
            app.PulsewidthmsEditFieldLabel = uilabel(app.UIFigure);
            app.PulsewidthmsEditFieldLabel.Text = 'Pulse width (ms)';
            app.PulseWidthEditField_ch1 = uieditfield(app.UIFigure, 'numeric');
            app.PulseWidthEditField_ch1.ValueChangedFcn = createCallbackFcn(app, @PulseWidthEditField_ch1ValueChanged, true);
            app.PulseWidthEditField_ch1.Value = 500;
            app.FrequencyPulsesLabel_ch2 = uilabel(app.UIFigure);
            app.FrequencyPulsesLabel_ch2.Text = 'Frequency (Hz):';
            app.NumPulsesLabel_ch2 = uilabel(app.UIFigure);
            app.NumPulsesLabel_ch2.Text = 'N pulses:';
            app.ArmTTLCheckBox_ch2 = uicheckbox(app.UIFigure);
            app.ArmTTLCheckBox_ch2.ValueChangedFcn = createCallbackFcn(app, @ArmTTLCheckBox_ch2ValueChanged, true);
            app.ArmTTLCheckBox_ch2.Enable = 'off';
            app.ArmTTLCheckBox_ch2.Text = 'Active';
            app.AmplitudeEditFieldLabel_2 = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel_2.Enable = 'off';
            app.AmplitudeEditFieldLabel_2.Text = 'Amplitude ';
            app.AmplitudeEditField_ch2 = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField_ch2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_ch2ValueChanged, true);
            app.AmplitudeEditField_ch2.Enable = 'off';
            app.AmplitudeEditField_ch2.Value = 1;
            app.DelaymsEditFieldLabel_2 = uilabel(app.UIFigure);
            app.DelaymsEditFieldLabel_2.Text = 'Delay (ms)';
            app.DelayEditField_ch2 = uieditfield(app.UIFigure, 'numeric');
            app.DelayEditField_ch2.ValueChangedFcn = createCallbackFcn(app, @DelayEditField_ch2ValueChanged, true);
            app.PeriodmsEditFieldLabel_2 = uilabel(app.UIFigure);
            app.PeriodmsEditFieldLabel_2.Text = 'Period (ms)';
            app.PeriodEditField_ch2 = uieditfield(app.UIFigure, 'numeric');
            app.PeriodEditField_ch2.ValueChangedFcn = createCallbackFcn(app, @PeriodEditField_ch2ValueChanged, true);
            app.DurationmsEditField_2Label_2 = uilabel(app.UIFigure);
            app.DurationmsEditField_2Label_2.Text = 'Duration (ms)';
            app.DurationEditField_ch2 = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField_ch2.ValueChangedFcn = createCallbackFcn(app, @DurationEditField_ch2ValueChanged, true);
            app.DurationEditField_ch2.Value = 1000;
            app.PulsewidthmsEditFieldLabel_2 = uilabel(app.UIFigure);
            app.PulsewidthmsEditFieldLabel_2.Text = 'Pulse width (ms)';
            app.PulseWidthEditField_ch2 = uieditfield(app.UIFigure, 'numeric');
            app.PulseWidthEditField_ch2.ValueChangedFcn = createCallbackFcn(app, @PulseWidthEditField_ch2ValueChanged, true);
            app.PulseWidthEditField_ch2.Value = 1000;
            app.Channel2Label = uilabel(app.UIFigure);
            app.Channel2Label.Text = 'Channel 2';
            app.ch2displayCheckBox = uicheckbox(app.UIFigure);
            app.ch2displayCheckBox.ValueChangedFcn = createCallbackFcn(app, @ch2displayCheckBoxValueChanged, true);
            app.ch2displayCheckBox.Text = 'ch2 display';
            app.ch2displayCheckBox.Value = true;
            app.ch1displayCheckBox = uicheckbox(app.UIFigure);
            app.ch1displayCheckBox.ValueChangedFcn = createCallbackFcn(app, @ch1displayCheckBoxValueChanged, true);
            app.ch1displayCheckBox.Text = 'ch1 display';
            app.ch1displayCheckBox.Value = true;
            app.DigitaloutputlinesLabel = uilabel(app.UIFigure);
            app.DigitaloutputlinesLabel.Text = 'Digital output lines';
            app.ExpDesignButtonGroup = uibuttongroup(app.UIFigure);
            app.ExpDesignButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExpDesignButtonGroupSelectionChanged, true);
            app.ExpDesignButtonGroup.Title = 'Experimental design:';
            app.BlockButton = uiradiobutton(app.ExpDesignButtonGroup);
            app.BlockButton.Text = 'Block';
            app.BlockButton.Value = true;
            app.TrialButton = uiradiobutton(app.ExpDesignButtonGroup);
            app.TrialButton.Text = 'Trial';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'platynereis dumerilii.png';
            app.BlockDesignPanel = uipanel(app.UIFigure);
            app.BlockDesignPanel.Title = 'Block design';
            app.BlockDesignButtonGroup = uibuttongroup(app.BlockDesignPanel);
            app.BlockDesignButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BlockDesignButtonGroupSelectionChanged, true);
            app.BlockDesignButtonGroup.Title = 'Block design';
            app.FirstHalfButton = uiradiobutton(app.BlockDesignButtonGroup);
            app.FirstHalfButton.Text = 'First half';
            app.SecondHalfButton = uiradiobutton(app.BlockDesignButtonGroup);
            app.SecondHalfButton.Text = 'Second half';
            app.ContinuousButton = uiradiobutton(app.BlockDesignButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.AlternateBlocksButton = uiradiobutton(app.BlockDesignButtonGroup);
            app.AlternateBlocksButton.Text = 'Alternate';
            app.AlternateBlocksButton.Value = true;
            app.TTLCh1startLabel = uilabel(app.BlockDesignPanel);
            app.TTLCh1startLabel.Text = 'TTL Ch1 start:';
            app.SessionStartCheckBox = uicheckbox(app.BlockDesignPanel);
            app.SessionStartCheckBox.ValueChangedFcn = createCallbackFcn(app, @SessionStartCheckBoxValueChanged, true);
            app.SessionStartCheckBox.Text = 'Session start';
            app.SessionStartCheckBox.Value = true;
            app.NextBlockCheckBox = uicheckbox(app.BlockDesignPanel);
            app.NextBlockCheckBox.ValueChangedFcn = createCallbackFcn(app, @NextBlockCheckBoxValueChanged, true);
            app.NextBlockCheckBox.Text = 'Next block';
            app.RepeatTTLCh1ButtonGroup = uibuttongroup(app.BlockDesignPanel);
            app.RepeatTTLCh1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RepeatTTLCh1ButtonGroupSelectionChanged, true);
            app.RepeatTTLCh1ButtonGroup.Title = 'Repeat TTL ch1';
            app.EveryNTrialsButton = uiradiobutton(app.RepeatTTLCh1ButtonGroup);
            app.EveryNTrialsButton.Text = 'Every             trials';
            app.EveryNTrialsButton.Value = true;
            app.EveryBlockStartButton = uiradiobutton(app.RepeatTTLCh1ButtonGroup);
            app.EveryBlockStartButton.Text = 'Every block start';
            app.NeverButton = uiradiobutton(app.RepeatTTLCh1ButtonGroup);
            app.NeverButton.Text = 'Never';
            app.RepeatTrialsEditField = uieditfield(app.RepeatTTLCh1ButtonGroup, 'numeric');
            app.RepeatTrialsEditField.ValueChangedFcn = createCallbackFcn(app, @RepeatTrialsEditFieldValueChanged, true);
            app.RepeatTrialsEditField.Value = 10;
            app.TTLCh2StartButton = uibutton(app.BlockDesignPanel, 'state');
            app.TTLCh2StartButton.ValueChangedFcn = createCallbackFcn(app, @TTLCh2StartButtonValueChanged, true);
            app.TTLCh2StartButton.Text = 'TTL ch2 start';
            app.TTLCh2ScheduleLamp = uilamp(app.BlockDesignPanel);
            app.TTLCh1ScheduleLamp = uilamp(app.BlockDesignPanel);
            app.TrialDesignPanel = uipanel(app.UIFigure);
            app.TrialDesignPanel.Title = 'Trial design';
            app.ProbabilitiesLabel = uilabel(app.TrialDesignPanel);
            app.ProbabilitiesLabel.Text = 'Probabilities:';
            app.AuditoryEditFieldLabel = uilabel(app.TrialDesignPanel);
            app.AuditoryEditFieldLabel.Text = 'Auditory';
            app.AuditoryEditField = uieditfield(app.TrialDesignPanel, 'numeric');
            app.AuditoryEditField.ValueChangedFcn = createCallbackFcn(app, @AuditoryEditFieldValueChanged, true);
            app.AuditoryEditField.Value = 1;
            app.WhiskerEditFieldLabel = uilabel(app.TrialDesignPanel);
            app.WhiskerEditFieldLabel.Text = 'Whisker';
            app.WhiskerEditField = uieditfield(app.TrialDesignPanel, 'numeric');
            app.WhiskerEditField.ValueChangedFcn = createCallbackFcn(app, @WhiskerEditFieldValueChanged, true);
            app.WhiskerEditField.Value = 1;
            app.CatchEditFieldLabel = uilabel(app.TrialDesignPanel);
            app.CatchEditFieldLabel.Text = 'Catch';
            app.CatchEditField = uieditfield(app.TrialDesignPanel, 'numeric');
            app.CatchEditField.ValueChangedFcn = createCallbackFcn(app, @CatchEditFieldValueChanged, true);
            app.CatchEditField.Value = 1;
            app.UpdatePreview = uibutton(app.UIFigure, 'push');
            app.UpdatePreview.ButtonPushedFcn = createCallbackFcn(app, @UpdatePreviewButtonPushed, true);
            app.UpdatePreview.Text = 'Update preview';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTL_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end