classdef Opto_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NoStimEditField             matlab.ui.control.NumericEditField
        NoStimEditFieldLabel        matlab.ui.control.Label
        ControlEditField            matlab.ui.control.NumericEditField
        ControlEditFieldLabel       matlab.ui.control.Label
        WhiskerEditField            matlab.ui.control.NumericEditField
        WhiskerEditFieldLabel       matlab.ui.control.Label
        ProbabilitiesLabel          matlab.ui.control.Label
        BaselinemsEditField         matlab.ui.control.NumericEditField
        BaselinemsEditFieldLabel    matlab.ui.control.Label
        AuditoryEditField           matlab.ui.control.NumericEditField
        AuditoryEditFieldLabel      matlab.ui.control.Label
        AssignbregmaCheckBox        matlab.ui.control.CheckBox
        MLEditField                 matlab.ui.control.NumericEditField
        MLEditFieldLabel            matlab.ui.control.Label
        APEditField                 matlab.ui.control.NumericEditField
        APEditFieldLabel            matlab.ui.control.Label
        SetBregmaButton             matlab.ui.control.Button
        OnetrialButton              matlab.ui.control.Button
        EnvelopeButtonGroup         matlab.ui.container.ButtonGroup
        RampDownButton              matlab.ui.control.RadioButton
        GaussianButton              matlab.ui.control.RadioButton
        NoneButton_2                matlab.ui.control.RadioButton
        PulseShapeButtonGroup       matlab.ui.container.ButtonGroup
        CosineButton                matlab.ui.control.RadioButton
        SquareButton                matlab.ui.control.RadioButton
        GridDropDown                matlab.ui.control.DropDown
        GridDropDownLabel           matlab.ui.control.Label
        FrequencyHzEditField        matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel   matlab.ui.control.Label
        DurationmsEditField         matlab.ui.control.NumericEditField
        DurationmsEditFieldLabel    matlab.ui.control.Label
        PulseWidthmsEditField       matlab.ui.control.NumericEditField
        PulseWidthmsEditFieldLabel  matlab.ui.control.Label
        AmplitudemVEditField        matlab.ui.control.NumericEditField
        AmplitudemVEditFieldLabel   matlab.ui.control.Label
        SetParamsButton             matlab.ui.control.Button
        OptogeneticsLabel           matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = public)

        grid = '';
        AP_coord    = 0;
        ML_coord    = 0;
        baseline    = [];
        amplitude   = [];
        pulse_width = [];
        duration    = [];
        frequency   = [];
        aud_proba   = [];
        wh_proba    = [];
        nostim_proba= [];
        ctrl_proba  = [];
        pulse_shape = '';
        envelope    = '';

    end
    methods (Access = public)
        
        function results = update_opto_info(app)
            app.grid            = app.GridDropDown.Value;
            app.AP_coord        = app.APEditField.Value;
            app.ML_coord        = app.MLEditField.Value;
            app.baseline        = app.BaselinemsEditField.Value/1000;
            app.amplitude       = app.AmplitudemVEditField.Value;
            app.pulse_width     = app.PulseWidthmsEditField.Value/1000;
            app.duration        = app.DurationmsEditField.Value/1000;
            app.frequency       = app.FrequencyHzEditField.Value;
            app.aud_proba       = app.AuditoryEditField.Value;
            app.wh_proba        = app.WhiskerEditField.Value;
            app.nostim_proba      = app.NoStimEditField.Value;
            app.ctrl_proba      = app.ControlEditField.Value;

            if app.CosineButton.Value
                app.pulse_shape = 'cosine';
            elseif app.SquareButton.Value
                app.pulse_shape = 'square';
            end

            if app.NoneButton_2.Value
                app.envelope    = 'None';
            elseif app.GaussianButton.Value
                app.envelope    = 'Gaussian';
            elseif app.RampDownButton.Value
                app.envelope    = 'Ramp';
            end

            global Opto_info
            Opto_info.grid          = app.grid;
            Opto_info.AP_coord      = app.AP_coord;
            Opto_info.ML_coord      = app.ML_coord;
            Opto_info.baseline      = app.baseline;
            Opto_info.amplitude     = app.amplitude;
            Opto_info.pulse_width   = app.pulse_width;
            Opto_info.duration      = app.duration;
            Opto_info.frequency     = app.frequency;
            Opto_info.aud_proba     = app.aud_proba;
            Opto_info.wh_proba      = app.wh_proba;
            Opto_info.nostim_proba  = app.nostim_proba;
            Opto_info.ctrl_proba    = app.ctrl_proba;
            Opto_info.pulse_shape   = app.pulse_shape;
            Opto_info.envelope      = app.envelope;
            Opto_info.setup         = getenv('COMPUTERNAME');

            plot_grid(app)
            plot_pulsetrain(app)

        end
        
        function [pos_x, pos_y] = pos_from_bregma(app)
            global handles2give
            root_path = 'M:\analysis\Pol_Bech\Parameters\Optogenetics\';
            [bregma_x, bregma_y] = get_bregma(root_path, handles2give.mouse_name);
            disp([handles2give.mouse_name ' Current bregma values: ' num2str(bregma_x) ' ML, ' num2str(bregma_y) ' AP'])

            [x_step, y_step] = get_step(getenv('COMPUTERNAME'));
            pos_x = bregma_x + app.ML_coord*x_step;
            pos_y = bregma_y + app.AP_coord*y_step;
        end
        
        function results = plot_grid(app)
            cla(app.UIAxes)
            
            app.UIAxes.Title.String = 'Stimulation Points';
            app.UIAxes.YLim = [-1, 7];
            app.UIAxes.YTick = [-1:7];
            app.UIAxes.YLabel.String = 'ML';
            app.UIAxes.XLim = [-6, 4];
            app.UIAxes.XTick = [-6:4];
            app.UIAxes.XLabel.String = 'AP';

            brain_shape = polyshape([3.75, 3.75, 3.25, 2.75, 2.25, 1.75, 1.25, 0.75, -4.75, -4.75, -4.75, -5.25, -5.25, -4.75, -4.75],...
                                   [0.25, 3.25, 3.75, 4.25, 4.75, 5.25, 5.75, 6.25,  6.25,  5.25,  4.75,  4.75,  1.75,  1.75,  0.25]);

            plot(app.UIAxes, brain_shape, 'FaceColor', '#e3dac9', 'EdgeColor','black', 'FaceAlpha', 0.25)
            hold(app.UIAxes, 'on')

            [x, y] = meshgrid(-1:7, -6:4);
            line(app.UIAxes, y, x, 'Color', '#ECECEC')
            [x, y] = meshgrid(-6:4, -1:7);
            line(app.UIAxes, x, y, 'Color', '#ECECEC')
            [x, y] = meshgrid(-0.5:6.5, -6:4);
            line(app.UIAxes, y, x, 'Color', '#ECECEC', 'LineStyle', ':')
            [x, y] = meshgrid(-5.5:3.5, -1:7);  
            line(app.UIAxes, x, y, 'Color', '#ECECEC', 'LineStyle', ':')

            scatter(app.UIAxes, 0,0, 'red', 'Marker','+', 'LineWidth',1)

            [stim_x, stim_y] = get_stim_grid(app.grid, app.AP_coord, app.ML_coord);
            scatter(app.UIAxes, stim_x, stim_y, 'MarkerEdgeColor','#1338BE');

        end
        
        function results = plot_pulsetrain(app)
            global handles2give 
            
            if is_wf_computer(getenv("COMPUTERNAME"))
                global Opto_S
                if isempty(Opto_S)
                    opto_setup
                end
            else
                global Stim_S
                if isempty(Stim_S)
                    Stim_S = daq.createSession('ni');
                    Stim_S_SR = 100000;
                    aoch_coil=addAnalogOutputChannel(Stim_S,'Dev2','ao0', 'Voltage'); % Whisker stim (coil/piezo) channel
                    aoch_coil.TerminalConfig='SingleEnded';
                
                    aoch_aud=addAnalogOutputChannel(Stim_S,'Dev2','ao1', 'Voltage'); % Auditory  stim (tone) channel
                    aoch_aud.TerminalConfig='SingleEnded';
                
                    aoch_opto=addAnalogOutputChannel(Stim_S,'Dev2','ao2', 'Voltage'); % Opto  stim channel
                    aoch_opto.TerminalConfig='SingleEnded';
                
                    addDigitalChannel(Stim_S,'Dev2', 'Port0/Line0', 'OutputOnly'); %  Camera Channel
                    addDigitalChannel(Stim_S,'Dev2', 'Port0/Line1', 'OutputOnly'); %  ScanImage Trigger Channel (2P-imaging)
                
                    addTriggerConnection(Stim_S,'External','Dev2/PFI0','StartTrigger');
                
                    Stim_S.Rate = Stim_S_SR;
                    Stim_S.IsContinuous = true;
                    Stim_S.TriggersPerRun = 1;
                    Stim_S.ExternalTriggerTimeout = 2000;
                end
            end
            
            app.UIAxes2.Title.String = 'Pulse train';
            app.UIAxes2.YLabel.String = 'Amplitude (mV)';
            app.UIAxes2.XLabel.String = 'Time (ms)';

            [t, opto_vec] = get_opto_vec(handles2give.trial_duration/1000);
            plot(app.UIAxes2, t, opto_vec)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetParamsButton
        function SetParamsButtonPushed(app, event)
            update_opto_info(app);
        end

        % Value changed function: AmplitudemVEditField
        function AmplitudemVEditFieldValueChanged(app, event)
            value = app.AmplitudemVEditField.Value;
            update_opto_info(app);
        end

        % Value changed function: PulseWidthmsEditField
        function PulseWidthmsEditFieldValueChanged(app, event)
            value = app.PulseWidthmsEditField.Value;
            update_opto_info(app);
        end

        % Value changed function: DurationmsEditField
        function DurationmsEditFieldValueChanged(app, event)
            value = app.DurationmsEditField.Value;
            update_opto_info(app);
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            value = app.FrequencyHzEditField.Value;
            update_opto_info(app);
        end

        % Value changed function: GridDropDown
        function GridDropDownValueChanged(app, event)
            value = app.GridDropDown.Value;
            update_opto_info(app);
        end

        % Value changed function: APEditField
        function APEditFieldValueChanged(app, event)
            value = app.APEditField.Value;
            update_opto_info(app);
        end

        % Value changed function: MLEditField
        function MLEditFieldValueChanged(app, event)
            value = app.MLEditField.Value;
            update_opto_info(app);
        end

        % Selection changed function: EnvelopeButtonGroup
        function EnvelopeButtonGroupSelectionChanged(app, event)
            selectedButton = app.EnvelopeButtonGroup.SelectedObject;
            update_opto_info(app);
        end

        % Selection changed function: PulseShapeButtonGroup
        function PulseShapeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PulseShapeButtonGroup.SelectedObject;
            update_opto_info(app);
        end

        % Button pushed function: OnetrialButton
        function OnetrialButtonPushed(app, event)
            global Opto_S %handles2give
%             root_path = 'M:\analysis\Pol_Bech\Parameters\';
            update_opto_info(app);
            opto_setup
            trial_dur = 1;
            % Make laser vector
            t = 0:1/Opto_S.Rate:trial_dur; % time vector
            w = app.pulse_width; % pulse width
            d = w/2 : 1/app.frequency : t((length(t)+1)/2);
            y = pulstran(t(1:end/2), d, 'rectpuls', w);
            opto_vec = [-1*ones([1,200*Opto_S.Rate/1000]) y*app.amplitude -1*ones([1, round((trial_dur-0.2-trial_dur/2)*Opto_S.Rate)])]; % create opto vector in ms

            if is_wf_computer(getenv("COMPUTERNAME"))
                % Move galvos into position
    
                [pos_x, pos_y] = pos_from_bregma(app);
                galv_x = pos_x*ones([1, length(t)-1]);
                galv_y = pos_y*ones([1, length(t)-1]);
    %             Opto_S.write([opto_vec; galv_x; galv_y]')
                Opto_S.queueOutputData([opto_vec; galv_x; galv_y]');
                Opto_S.startForeground();
            else
                Stim_S_SR = 100000;
                wh_vec = zeros(1,(trial_dur)*(Stim_S_SR/1000));
                aud_vec = zeros(1,(trial_dur)*(Stim_S_SR/1000));
                camera_vec = zeros(1,(trial_dur)*(Stim_S_SR/1000));
                SITrigger_vec=[ones(1,numel(wh_vec)-2) 0 0];
                queueOutputData(Stim_S,[wh_vec; aud_vec; opto_vec; camera_vec; SITrigger_vec]')
                Stim_S.startForeground();

            end
        end

        % Button pushed function: SetBregmaButton
        function SetBregmaButtonPushed(app, event)
            global handles2give
            root_path = 'M:\analysis\Pol_Bech\Parameters\Optogenetics\';
            [pos_x, pos_y] = pos_from_bregma(app);
            saved = set_bregma(root_path, handles2give.mouse_name, pos_x, pos_y);
            if saved
                app.APEditField.Value = 0;
                app.MLEditField.Value = 0;
                update_opto_info(app)
            end
        end

        % Value changed function: AssignbregmaCheckBox
        function AssignbregmaCheckBoxValueChanged(app, event)
            value = app.AssignbregmaCheckBox.Value;
            if value
                app.SetBregmaButton.Enable  = 'on';
                app.OnetrialButton.Enable   = 'on';
            else
                app.SetBregmaButton.Enable  = 'off';
                app.OnetrialButton.Enable   = 'off';
            end
        end

        % Value changed function: AuditoryEditField
        function AuditoryEditFieldValueChanged(app, event)
            value = app.AuditoryEditField.Value;
            update_opto_info(app);
        end

        % Value changed function: WhiskerEditField
        function WhiskerEditFieldValueChanged(app, event)
            value = app.WhiskerEditField.Value;
            update_opto_info(app);
        end

        % Value changed function: NoStimEditField
        function NoStimEditFieldValueChanged(app, event)
            value = app.NoStimEditField.Value;
            update_opto_info(app);
        end

        % Value changed function: ControlEditField
        function ControlEditFieldValueChanged(app, event)
            value = app.ControlEditField.Value;
            update_opto_info(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.OptogeneticsLabel = uilabel(app.UIFigure);
            app.OptogeneticsLabel.Text = 'Optogenetics';
            app.SetParamsButton = uibutton(app.UIFigure, 'push');
            app.SetParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SetParamsButtonPushed, true);
            app.SetParamsButton.Text = 'Set Params';
            app.AmplitudemVEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudemVEditFieldLabel.Text = 'Amplitude (mV)';
            app.AmplitudemVEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudemVEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudemVEditFieldValueChanged, true);
            app.AmplitudemVEditField.Value = 0.1;
            app.PulseWidthmsEditFieldLabel = uilabel(app.UIFigure);
            app.PulseWidthmsEditFieldLabel.Text = 'Pulse Width (ms)';
            app.PulseWidthmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PulseWidthmsEditField.ValueChangedFcn = createCallbackFcn(app, @PulseWidthmsEditFieldValueChanged, true);
            app.PulseWidthmsEditField.Value = 4;
            app.DurationmsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationmsEditFieldLabel.Text = 'Duration (ms)';
            app.DurationmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @DurationmsEditFieldValueChanged, true);
            app.DurationmsEditField.Value = 500;
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.FrequencyHzEditField.Value = 50;
            app.GridDropDownLabel = uilabel(app.UIFigure);
            app.GridDropDownLabel.Text = 'Grid';
            app.GridDropDown = uidropdown(app.UIFigure);
            app.GridDropDown.Items = {'No Grid', 'Grid 1', 'Grid 2', 'Grid 3', 'Grid 4', '1mm optogrid perm 1', '1mm optogrid perm 2', '1mm optogrid perm 3'};
            app.GridDropDown.ValueChangedFcn = createCallbackFcn(app, @GridDropDownValueChanged, true);
            app.GridDropDown.Value = 'No Grid';
            app.PulseShapeButtonGroup = uibuttongroup(app.UIFigure);
            app.PulseShapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PulseShapeButtonGroupSelectionChanged, true);
            app.PulseShapeButtonGroup.Title = 'Pulse Shape';
            app.SquareButton = uiradiobutton(app.PulseShapeButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SquareButton.Value = true;
            app.CosineButton = uiradiobutton(app.PulseShapeButtonGroup);
            app.CosineButton.Text = 'Cosine';
            app.EnvelopeButtonGroup = uibuttongroup(app.UIFigure);
            app.EnvelopeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EnvelopeButtonGroupSelectionChanged, true);
            app.EnvelopeButtonGroup.Title = 'Envelope';
            app.NoneButton_2 = uiradiobutton(app.EnvelopeButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.GaussianButton = uiradiobutton(app.EnvelopeButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.RampDownButton = uiradiobutton(app.EnvelopeButtonGroup);
            app.RampDownButton.Text = 'Ramp Down';
            app.OnetrialButton = uibutton(app.UIFigure, 'push');
            app.OnetrialButton.ButtonPushedFcn = createCallbackFcn(app, @OnetrialButtonPushed, true);
            app.OnetrialButton.Enable = 'off';
            app.OnetrialButton.Text = 'One trial';
            app.SetBregmaButton = uibutton(app.UIFigure, 'push');
            app.SetBregmaButton.ButtonPushedFcn = createCallbackFcn(app, @SetBregmaButtonPushed, true);
            app.SetBregmaButton.Enable = 'off';
            app.SetBregmaButton.Text = 'Set Bregma';
            app.APEditFieldLabel = uilabel(app.UIFigure);
            app.APEditFieldLabel.Text = 'AP';
            app.APEditField = uieditfield(app.UIFigure, 'numeric');
            app.APEditField.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.MLEditFieldLabel = uilabel(app.UIFigure);
            app.MLEditFieldLabel.Text = 'ML';
            app.MLEditField = uieditfield(app.UIFigure, 'numeric');
            app.MLEditField.ValueChangedFcn = createCallbackFcn(app, @MLEditFieldValueChanged, true);
            app.AssignbregmaCheckBox = uicheckbox(app.UIFigure);
            app.AssignbregmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @AssignbregmaCheckBoxValueChanged, true);
            app.AssignbregmaCheckBox.Text = 'Assign bregma';
            app.AuditoryEditFieldLabel = uilabel(app.UIFigure);
            app.AuditoryEditFieldLabel.Text = 'Auditory';
            app.AuditoryEditField = uieditfield(app.UIFigure, 'numeric');
            app.AuditoryEditField.ValueChangedFcn = createCallbackFcn(app, @AuditoryEditFieldValueChanged, true);
            app.AuditoryEditField.Value = 1;
            app.BaselinemsEditFieldLabel = uilabel(app.UIFigure);
            app.BaselinemsEditFieldLabel.Text = 'Baseline (ms)';
            app.BaselinemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaselinemsEditField.Value = 200;
            app.ProbabilitiesLabel = uilabel(app.UIFigure);
            app.ProbabilitiesLabel.Text = 'Probabilities:';
            app.WhiskerEditFieldLabel = uilabel(app.UIFigure);
            app.WhiskerEditFieldLabel.Text = 'Whisker';
            app.WhiskerEditField = uieditfield(app.UIFigure, 'numeric');
            app.WhiskerEditField.ValueChangedFcn = createCallbackFcn(app, @WhiskerEditFieldValueChanged, true);
            app.WhiskerEditField.Value = 1;
            app.ControlEditFieldLabel = uilabel(app.UIFigure);
            app.ControlEditFieldLabel.Text = 'Control';
            app.ControlEditField = uieditfield(app.UIFigure, 'numeric');
            app.ControlEditField.ValueChangedFcn = createCallbackFcn(app, @ControlEditFieldValueChanged, true);
            app.ControlEditField.Value = 1;
            app.NoStimEditFieldLabel = uilabel(app.UIFigure);
            app.NoStimEditFieldLabel.Text = 'NoStim';
            app.NoStimEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoStimEditField.ValueChangedFcn = createCallbackFcn(app, @NoStimEditFieldValueChanged, true);
            app.NoStimEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Opto_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end